classdef interactive_track_cropper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        TextArea             matlab.ui.control.TextArea
        ReadyLamp            matlab.ui.control.Lamp
        ReadyLampLabel       matlab.ui.control.Label
        SavingLamp           matlab.ui.control.Lamp
        SavingLampLabel      matlab.ui.control.Label
        LoadingLamp          matlab.ui.control.Lamp
        LoadingLampLabel     matlab.ui.control.Label
        FrameSlider          matlab.ui.control.Slider
        FrameSliderLabel     matlab.ui.control.Label
        TrackSpinner         matlab.ui.control.Spinner
        TrackSpinnerLabel    matlab.ui.control.Label
        CropButton           matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        TrackPlot            matlab.ui.control.UIAxes
        FluorescenceCropped  matlab.ui.control.UIAxes
        Brightfield          matlab.ui.control.UIAxes
        BrightfieldCropped   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        folderPath % folder in which the performs analysis/saves files
        brightfieldStack % Description
        fluorescenceStack % Description
        tracksCell % Cell containing all the tracks
        cropped_brightfieldStack % 
        cropped_fluorescenceStack %
        currentTrack % Current track selected
    end
    
    methods (Access = private)
        
        function lamps(app,status)
            switch status
                case 'loading'
                    app.LoadingLamp.Enable = 'on';
                    app.SavingLamp.Enable = 'off';
                    app.ReadyLamp.Enable = 'off';
                case 'saving'
                    app.LoadingLamp.Enable = 'off';
                    app.SavingLamp.Enable = 'on';
                    app.ReadyLamp.Enable = 'off';
                case 'ready'
                    app.LoadingLamp.Enable = 'off';
                    app.SavingLamp.Enable = 'off';
                    app.ReadyLamp.Enable = 'on';
            end

        end
        
        function croppedFrames = cropFrames(app,frameStack,track)
                N = size(track,1); % length of the track
                L = 200; % Size of cropped square
                
                croppedFrames = zeros(L+1,L+1,N); % pre-allocate memory for speed
                
                for i=1:N
                    T = track(i,1);
                    x = track(i,2);
                    y = track(i,3);

                    currentFrame = frameStack(:,:,T);
                    croppedFrames(:,:,i) = imcrop(currentFrame, [x-L/2,y-L/2,L,L]); % crop image
                end
        end
        
        function croppedFrames = cropFramesFluo(app,frameStack,track)
            N = size(track,1); % length of the track
            L = 200; % Size of cropped square

            croppedFrames = zeros(L+1,L+1,N); % pre-allocate memory for speed

            for i=1:N
                T = track(i,1);
                x = track(i,2);
                y = track(i,3);

                currentFrame = frameStack(:,:,T);
                croppedFrames(:,:,i) = imgaussfilt(imcrop(currentFrame, [x-L/2,y-L/2,L,L]),3); % crop image
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, folderpath)
            % set up for images
            set(app.Brightfield,'Visible','off');
            set(app.BrightfieldCropped,'Visible','off');
            set(app.FluorescenceCropped,'Visible','off');

            app.Brightfield.DataAspectRatio = [1 1 1];
            app.BrightfieldCropped.DataAspectRatio = [1 1 1];
            app.FluorescenceCropped.DataAspectRatio = [1 1 1];
            
            lamps(app,'loading')

            app.folderPath = folderpath;

            % load up the full images
            app.brightfieldStack = loadtiff([folderpath 'brightfield.tiff']);
            app.fluorescenceStack = loadtiff([folderpath 'L.tiff']);

            % load the tracksCell
            app.tracksCell = importdata([folderpath 'allBacteriaTracks.mat']);
         
            % set limit to TrackSpinner based on length tracksCell
            Ntracks = length(app.tracksCell);
            label = sprintf('Track (N=%i)', Ntracks);
            app.TrackSpinnerLabel.Text = label;

            if Ntracks>1
            set(app.TrackSpinner,'Limits', [1 Ntracks]);
            set(app.TrackSpinner, 'Value', 1)
            end
            % crop frames
            track = app.tracksCell{1};
            app.currentTrack = track;

            trackStartFrame = track(1,1);
            trackEndFrame = track(end,1);

            app.FrameSlider.Limits = [trackStartFrame trackEndFrame];

            app.cropped_brightfieldStack=cropFrames(app,app.brightfieldStack,track);
            app.cropped_fluorescenceStack=cropFramesFluo(app,app.fluorescenceStack,track);

            imagesc(app.BrightfieldCropped, app.cropped_brightfieldStack(:,:,1));
            imagesc(app.Brightfield, app.brightfieldStack(:,:,1));
            imagesc(app.FluorescenceCropped, app.cropped_fluorescenceStack(:,:,1));

            lamps(app,'ready')

            % plot first track
            app.TrackPlot.XLim=[1 size(app.brightfieldStack,2)];
            app.TrackPlot.YLim=[1 size(app.brightfieldStack,1)];
            imagesc(app.TrackPlot,app.fluorescenceStack(:,:,1));

            index = 1:size(track,1);
            T = track(index,1);
            x = track(index,2);
            y = track(index,3);
            cols = turbo(length(T));
            hold(app.TrackPlot,"on");
            %plot(app.TrackPlot,x,y,'k-');
            scatter(app.TrackPlot,x,y,3,cols,'filled');

            allX = track(:,2); allY = track(:,3);

            % change XLim, YLim of brightfield
            set(app.Brightfield,'XLim',[min(allX)-150 max(allX)+150]);
            set(app.Brightfield,'YLim',[min(allY)-150 max(allY)+150]);

            set(app.TrackPlot,'XLim',[min(allX)-150 max(allX)+150]);
            set(app.TrackPlot,'YLim',[min(allY)-150 max(allY)+150]);
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);

            image_index = app.currentTrack(changingValue,1);
            
            % update plots
            
            imshow(app.cropped_brightfieldStack(:,:,image_index), [],'Parent',app.BrightfieldCropped);
            imshow(app.brightfieldStack(:,:,image_index), [], 'Parent', app.Brightfield);
            imshow(app.cropped_fluorescenceStack(:,:,image_index), [], 'Parent', app.FluorescenceCropped);
            imshow(imgaussfilt(app.fluorescenceStack(:,:,image_index),2),[],'Parent',app.TrackPlot);

%             imagesc(app.BrightfieldCropped, app.cropped_brightfieldStack(:,:,image_index));
%             imagesc(app.Brightfield, app.brightfieldStack(:,:,image_index));
%             imagesc(app.FluorescenceCropped, app.cropped_fluorescenceStack(:,:,image_index).*2);

            % trajectory
            index = 1:changingValue;
            T = app.currentTrack(index,1);
            x = app.currentTrack(index,2);
            y = app.currentTrack(index,3);
            hold(app.Brightfield,'on');
            cols = turbo(length(T));
            scatter(app.Brightfield,x,y,10,cols,'filled');
            hold(app.Brightfield,'off');
        end

        % Value changed function: TrackSpinner
        function TrackSpinnerValueChanged(app, event)
            value = app.TrackSpinner.Value;
            
            % when the TrackSpinner changes value, you must replot track
            % and recrop frames
            track = app.tracksCell{value};
            app.currentTrack = track;

            % Change slider value to cover only portion covered by track
            trackStartFrame = track(1,1);
            trackEndFrame = track(end,1);

            app.FrameSlider.Limits = [trackStartFrame trackEndFrame];

            app.cropped_brightfieldStack=cropFrames(app,app.brightfieldStack,track);
            app.cropped_fluorescenceStack=cropFramesFluo(app,app.fluorescenceStack,track);

            imagesc(app.BrightfieldCropped, app.cropped_brightfieldStack(:,:,1));
            imagesc(app.Brightfield, app.brightfieldStack(:,:,1));
            imagesc(app.FluorescenceCropped, app.cropped_fluorescenceStack(:,:,1));

            lamps(app,'ready')

            % plot first track
            app.TrackPlot.XLim=[1 size(app.brightfieldStack,2)];
            app.TrackPlot.YLim=[1 size(app.brightfieldStack,1)];

            index = 1:size(track,1);
            T = track(index,1);
            x = track(index,2);
            y = track(index,3);
            cols = turbo(length(T));
            plot(app.TrackPlot,x,y,'k-');
            hold(app.TrackPlot,"on");
            scatter(app.TrackPlot,x,y,3,cols,'filled');

            allX = track(:,2); allY = track(:,3);
            % change XLim, YLim of brightfield
            set(app.Brightfield,'XLim',[min(allX)-150 max(allX)+150]);
            set(app.Brightfield,'YLim',[min(allY)-150 max(allY)+150]);
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            % when the button gets pushed, the cropped videos get cropped
            lamps(app,'saving');
            fprintf("Saving process...\n")
            % start by creating the directory where to save files
            track_id = app.TrackSpinner.Value;
            savepath = [app.folderPath 'cropped/cell_' num2str(track_id) '_cropped/'];

            if ~exist(savepath, 'dir')
                mkdir(savepath);
            end

            fprintf("Loading and cropping L/R frames... \n")
            % crop L and R without gauss blurring them, using cropFrames
            L = loadtiff([app.folderPath 'L.tiff']);
            Lcropped = uint8(cropFrames(app, L, app.currentTrack));

            R = loadtiff([app.folderPath 'R.tiff']);
            Rcropped = uint8(cropFrames(app, R, app.currentTrack));
            

            fprintf("Saving images and trajectory... \n")
            options.overwrite = true;
            options.message = true;

            % save stuff
            saveastiff(uint8(app.cropped_brightfieldStack), [savepath 'brightfield_cropped.tif'],options);
            saveastiff(Lcropped, [savepath 'L_cropped.tif'],options);
            saveastiff(Rcropped, [savepath 'R_cropped.tif'],options);
        
            % save bacteria trajectory as well
            tracktosave = app.currentTrack;
            % time needs to be aligned to the cropped ones
            T = tracktosave(:,1)-(tracktosave(1,1)-1);
            tracktosave(:,end+1) = tracktosave(:,1); % add a column with the times relative to the original image.
            tracktosave(:,1) = T;
            save([savepath 'bacteriaTrack.mat'], 'tracktosave');


            fprintf("Saving complete.\n")
            lamps(app,'ready');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {488, 488};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CropButton = uibutton(app.LeftPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.TrackSpinnerLabel = uilabel(app.LeftPanel);
            app.TrackSpinnerLabel.Text = 'Track';
            app.TrackSpinner = uispinner(app.LeftPanel);
            app.TrackSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackSpinnerValueChanged, true);
            app.FrameSliderLabel = uilabel(app.LeftPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.LeftPanel);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.LoadingLampLabel = uilabel(app.LeftPanel);
            app.LoadingLampLabel.Text = 'Loading';
            app.LoadingLamp = uilamp(app.LeftPanel);
            app.SavingLampLabel = uilabel(app.LeftPanel);
            app.SavingLampLabel.Text = 'Saving';
            app.SavingLamp = uilamp(app.LeftPanel);
            app.ReadyLampLabel = uilabel(app.LeftPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.LeftPanel);
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Wait for "Ready" before starting to move the frame!'};
            app.RightPanel = uipanel(app.GridLayout);
            app.BrightfieldCropped = uiaxes(app.RightPanel);
            title(app.BrightfieldCropped, 'Brightfield Cropped')
            xlabel(app.BrightfieldCropped, 'X')
            ylabel(app.BrightfieldCropped, 'Y')
            zlabel(app.BrightfieldCropped, 'Z')
            app.Brightfield = uiaxes(app.RightPanel);
            title(app.Brightfield, 'Brightfield')
            xlabel(app.Brightfield, 'X')
            ylabel(app.Brightfield, 'Y')
            zlabel(app.Brightfield, 'Z')
            app.FluorescenceCropped = uiaxes(app.RightPanel);
            title(app.FluorescenceCropped, 'Fluorescence Cropped')
            xlabel(app.FluorescenceCropped, 'X')
            ylabel(app.FluorescenceCropped, 'Y')
            zlabel(app.FluorescenceCropped, 'Z')
            app.TrackPlot = uiaxes(app.RightPanel);
            zlabel(app.TrackPlot, 'Z')
            app.TrackPlot.YDir = 'reverse';
            app.TrackPlot.XTickLabel = '';
            app.TrackPlot.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.TrackPlot.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactive_track_cropper(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end