classdef trackSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        TrackSelectorSpinner          matlab.ui.control.Spinner
        TrackSelectorSpinnerLabel     matlab.ui.control.Label
        PathEditField                 matlab.ui.control.EditField
        PathEditFieldLabel            matlab.ui.control.Label
        TrackStatisticsDropDown       matlab.ui.control.DropDown
        TrackStatisticsDropDownLabel  matlab.ui.control.Label
        ReadyLamp                     matlab.ui.control.Lamp
        ReadyLampLabel                matlab.ui.control.Label
        LoadingLamp                   matlab.ui.control.Lamp
        LoadingLampLabel              matlab.ui.control.Label
        CropButton                    matlab.ui.control.Button
        OpenFolderButton              matlab.ui.control.Button
        StatAxis                      matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        UsemousetozoomscrollorpressCroppedforCELLframeofreferenceLabel  matlab.ui.control.Label
        ResetZoomButton               matlab.ui.control.Button
        CroppedButton                 matlab.ui.control.StateButton
        Slider                        matlab.ui.control.Slider
        ButtonGroup                   matlab.ui.container.ButtonGroup
        OverlayButton                 matlab.ui.control.ToggleButton
        FluorescenceButton            matlab.ui.control.ToggleButton
        BrightfieldButton             matlab.ui.control.ToggleButton
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         brightfieldStack 
        fluorescenceStack
        allTracks
        currentTrack% Description
        hImage
        trackLine% Description
        slice % Description
        currentFrame
        fluorescenceStackR
        % Description
        fluorescenceStackL % Description
        statLine % Description
        currentStat % Description
        agarFlag % Description
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        
        function reloadImage(app)
            app.hImage.CData = app.currentFrame;
            % Scale image colorbar
            app.UIAxes.CLimMode='auto';

            % Check if cropped is on
            if app.CroppedButton.Value && ismember(app.slice,app.currentTrack(:,1))
                % Find current track position
                currentX = app.currentTrack(app.slice,2);
                currentY = app.currentTrack(app.slice,3);
                L = 50;

                app.UIAxes.XLim = [currentX-L currentX+L];
                app.UIAxes.YLim = [currentY-L currentY+L];

                set(app.trackLine(1),'XData',nan,'YData',nan);
                set(app.trackLine(2),'XData',nan,'YData',nan);

            elseif ~app.CroppedButton.Value
                

                set(app.trackLine(1),'XData',app.currentTrack(:,2),'YData',app.currentTrack(:,3));
                if ismember(app.slice,app.currentTrack(:,1))
                    idxTrackPlot = find(app.currentTrack(:,1)==app.slice);
                    set(app.trackLine(2),'XData',app.currentTrack(idxTrackPlot,2),'YData',app.currentTrack(idxTrackPlot,3));
                    %set(app.statLine(2),'XData',app.currentStat(app.slice,1))
                else
                    set(app.trackLine(2),'XData',nan,'YData',nan);
                end
            end
            
            if ~app.agarFlag
                if ismember(app.slice,app.currentStat(:,1))
                        idxStatPlot = find(app.currentStat(:,1)==app.slice);
                        set(app.statLine(2),'XData',app.currentStat(idxStatPlot,1),'YData',app.currentStat(idxStatPlot,2));
                        set(app.statLine(2),'Marker','o');
                else
                    %set(app.statLine(2),'XData',nan,'YData',nan);
                end
            end
        end
        
        function improveFluorescenceStack(app)
            for i=1:size(app.fluorescenceStack,3)
                %app.fluorescenceStack(:,:,i) = imsubtract(app.fluorescenceStack(:,:,i),imgaussfilt(app.fluorescenceStack(:,:,i),20));
                %app.fluorescenceStack(:,:,i) = imnlmfilt(app.fluorescenceStack(:,:,i));

                app.fluorescenceStack(:,:,i) = imgaussfilt(app.fluorescenceStack(:,:,i),2);
            end
        end
    end
    
    methods (Access = public)
        
        function improveBrightfieldStack(app)
            app.brightfieldStack(app.brightfieldStack==0) = 255;
            for i=1:size(app.brightfieldStack,3)
                app.brightfieldStack(:,:,i)=imadjust(app.brightfieldStack(:,:,i));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFolderButton
        function OpenFolderButtonPushed(app, event)
            try
                app.LoadingLamp.Color=[0 1 0];
                drawnow();
            catch
            end
            try
                currentFolder = app.PathEditField.Value;
                if currentFolder(end)=='\'
                else
                    currentFolder(end+1) = '\';
                end
    
                %-- Load the fluorescence and brightfield stack
                app.brightfieldStack = loadtiff([currentFolder 'brightfield.tiff']);

                if ~exist([currentFolder 'Lflatfield_denoised.tif'])
                    if ~exist([currentFolder 'Lflatfield.tif'])
                        app.fluorescenceStack = loadtiff([currentFolder 'L.tiff']);
                        app.fluorescenceStackR = loadtiff([currentFolder 'R.tiff']);
                    else
                        app.fluorescenceStack = loadtiff([currentFolder 'Lflatfield.tif']);
                        app.fluorescenceStackR = loadtiff([currentFolder 'Rflatfield.tif']);
                    end
                else
                    app.fluorescenceStack = loadtiff([currentFolder 'Lflatfield_denoised.tif']);
                    app.fluorescenceStackR = loadtiff([currentFolder 'Rflatfield_denoised.tif']);
                end

              
                %-- To make it work with agar pad experiments.
                % check if size brightfield stack is one
                if size(app.brightfieldStack,3)==1
                    app.brightfieldStack = repmat(app.brightfieldStack,[1 1 size(app.fluorescenceStack,3)]);
                    app.agarFlag = true;
                else
                    app.agarFlag = false;
                end

                % -- Store fluorescence stack(s)
                app.fluorescenceStackL = app.fluorescenceStack;
                
    
                % Turn fluorescence stack into 8-bit
                %app.fluorescenceStack = uint8(app.fluorescenceStack);

                %-- Load tracks
                app.allTracks = importdata([currentFolder 'allBacteriaTracks.mat']);
                app.currentTrack = app.allTracks{1};

                % Set limits of track selector
                if length(app.allTracks)>1
                    app.TrackSelectorSpinner.Enable = 'on';
                    app.TrackSelectorSpinner.Limits = [1 length(app.allTracks)];
                else
                    app.TrackSelectorSpinner.Enable = 'off';
                end

                % Process fluorescence stack
                fprintf("improving images \n")
                improveBrightfieldStack(app);
                improveFluorescenceStack(app);
                fprintf("image improved. \n")

                %-- PLOTTING
                hold(app.UIAxes,"off");
                app.hImage = imshow(app.brightfieldStack(:,:,1),[],'Parent', app.UIAxes);
                hold(app.UIAxes,"on");

                % Plot Track function.
                app.trackLine = plot(app.UIAxes,nan,nan,nan,nan,'-','LineWidth',2);
                
                %set(app.trackLine(1),'Color',[0 0 1 0.5]);
                set(app.trackLine(2),'Marker','o');
    
                %-- And set up various parameters for the axes
                app.UIAxes.XLim = [0 size(app.brightfieldStack,2)];
                app.UIAxes.YLim = [0 size(app.brightfieldStack,1)];
                app.UIAxes.Visible = false;
    
                %-- Set slider min and max
                app.Slider.Value = 1;
                app.Slider.Limits = [1 size(app.brightfieldStack,3)];
                app.slice=1;

                app.Label.Text = ['All files loaded correctly. ' num2str(length(app.allTracks)) ' tracks found.'];

                %-- set currentFrame
                if strcmp(app.ButtonGroup.SelectedObject.Text,'Brightfield')
                    app.currentFrame=app.brightfieldStack(:,:,app.slice);
                else
                    app.currentFrame=app.fluorescenceStack(:,:,app.slice);
                end


                %--- INITIALISE SECOND PLOT TOO
                hold(app.StatAxis,'off');
                app.statLine = plot(app.StatAxis,nan,nan,nan,nan,'-','LineWidth',2);
                hold(app.StatAxis,'on');
                % change statistics to speed, which should trigger
                % everything being set up
                if app.agarFlag
                    app.TrackStatisticsDropDown.Enable='off';
                else                   
                    app.TrackStatisticsDropDown.Value = 'Speed';
                    TrackStatisticsDropDownValueChanged(app, event);
                end

                %-- check if this changes performance
                hold(app.StatAxis,'off');
                hold(app.UIAxes,"off");
                %--- eliminate if note
                reloadImage(app);

               % Change lamp colors. 
                app.LoadingLamp.Color=[1 1 1];
                app.ReadyLamp.Color=[0 1 0];
            catch
                app.LoadingLamp.Color=[1 0 0];
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(app.ButtonGroup.SelectedObject.Text,'Brightfield')
                app.currentFrame=app.brightfieldStack(:,:,app.slice);
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Fluorescence')
                app.currentFrame=app.fluorescenceStack(:,:,app.slice);
            else
                app.currentFrame = imfuse(app.brightfieldStack(:,:,app.slice),app.fluorescenceStack(:,:,app.slice));
            end
            reloadImage(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.slice = ceil(changingValue);

            if strcmp(app.ButtonGroup.SelectedObject.Text,'Brightfield')
                app.currentFrame=app.brightfieldStack(:,:,app.slice);
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Fluorescence')
                app.currentFrame=app.fluorescenceStack(:,:,app.slice);
            else
                app.currentFrame = imfuse(app.brightfieldStack(:,:,app.slice),app.fluorescenceStack(:,:,app.slice));
            end
            
            reloadImage(app);
        end

        % Value changed function: CroppedButton
        function CroppedButtonValueChanged(app, event)
            value = app.CroppedButton.Value;
            if ~value
                app.UIAxes.XLim = [0 size(app.brightfieldStack,2)];
                app.UIAxes.YLim = [0 size(app.brightfieldStack,1)];
            end
            reloadImage(app);
        end

        % Value changed function: TrackSelectorSpinner
        function TrackSelectorSpinnerValueChanged(app, event)
            value = app.TrackSelectorSpinner.Value;
            % Need to refresh track
            app.currentTrack = app.allTracks{value};
            % ...and the stats
            if ~app.agarFlag
                TrackStatisticsDropDownValueChanged(app,event);
            end
            reloadImage(app);
        end

        % Value changed function: TrackStatisticsDropDown
        function TrackStatisticsDropDownValueChanged(app, event)
            value = app.TrackStatisticsDropDown.Value;
            
            if strcmp(value, 'Speed')
                X = (app.currentTrack(:,2));
                Y = (app.currentTrack(:,3));
                
                T = app.currentTrack(:,1);

                V = sqrt(diff(X).^2+diff(Y).^2);
                T(V==0)=[]; V(V==0)=[];
                T(isnan(V))=[]; V(isnan(V))=[]; 
                

                V = smooth((V.*0.12)./0.1);

                app.currentStat = [T(2:end) V];

                
                app.StatAxis.YLabel.String = 'V';
                app.StatAxis.Title.String = 'Speed';
            end

            if strcmp(value, 'Direction')
                T = app.currentTrack(:,1);
                X = smooth(app.currentTrack(:,2));
                Y = smooth(app.currentTrack(:,3));

                pX = polyfit(T,X,3);
                pY = polyfit(T,Y,3);

                X1 = polyval(pX,T);
                Y1 = polyval(pY,T);

                theta = atan2(diff(Y1),diff(X1)); % -pi to pi
%                T(theta==0)=[]; theta(theta==0)=[];
                T(isnan(theta))=[]; theta(isnan(theta))=[]; 
                
                app.currentStat = [T(2:end) theta];

                app.StatAxis.YLabel.String = 'Theta';
                app.StatAxis.Title.String = 'Direction';
            end

            set(app.statLine(1),'XData',app.currentStat(:,1),'YData',app.currentStat(:,2));
            
            if ismember(app.slice,app.currentStat(:,1))
                set(app.statLine(2),'XData',app.currentStat(app.currentStat(:,1)==app.slice,1),'YData',app.currentStat(app.currentStat(:,1)==app.slice,2));
                set(app.statLine(2),'Marker','o');
            end

%                plot(app.StatAxis,T(2:end),smooth(V./0.1));
            set(app.StatAxis,'PlotBoxAspectRatio',[1 1 1]);
            app.StatAxis.XLabel.String = 'Frame';
            %app.StatAxis.YLabel.String = 'V';
            %app.StatAxis.Title.String = 'Speed';
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            % Turn the ready button yellow
            app.ReadyLamp.Color=[1 1 0];
            drawnow();

            %--- START CROPPING

            boxLength = 100;
            track_id = app.TrackSelectorSpinner.Value;

            savepath = [app.PathEditField.Value '\cropped\cell_' num2str(track_id) '_cropped\'];
            
            if ~exist(savepath, 'dir')
                mkdir(savepath);
            end

            Bcropped = cropFramesAlongTrack(app.brightfieldStack,app.currentTrack,boxLength);
            Lcropped = cropFramesAlongTrack(app.fluorescenceStackL,app.currentTrack,boxLength);
            Rcropped = cropFramesAlongTrack(app.fluorescenceStackR,app.currentTrack,boxLength);
    
            fprintf("Saving images and trajectory... \n")
            options.overwrite = true;
            options.message = true;
    
            % save stuff
            saveastiff((Bcropped), [savepath 'brightfield_cropped.tif'],options);
            saveastiff((Lcropped), [savepath 'L_cropped.tif'],options);
            saveastiff((Rcropped), [savepath 'R_cropped.tif'],options);
        
            % save bacteria trajectory as well
            tracktosave = app.currentTrack;
            % time needs to be aligned to the cropped ones
            T = tracktosave(:,1)-(tracktosave(1,1)-1);
            tracktosave(:,end+1) = tracktosave(:,1); % add a column with the times relative to the original image.
            tracktosave(:,1) = T;
    
            save([savepath 'bacteriaTrack.mat'], 'tracktosave');
            
            fprintf("Saving complete.\n")

            %--- END OF CROPPING
            % Turn the ready button green
            app.ReadyLamp.Color=[0 1 0];
            drawnow();
        end

        % Button pushed function: ResetZoomButton
        function ResetZoomButtonPushed(app, event)
            % Reset cropped button value.
            if app.CroppedButton.Value
                app.CroppedButton.Value = false;
            end
            % Restore full FOV in the view.
            app.UIAxes.XLim = [0 size(app.brightfieldStack,2)];
            app.UIAxes.YLim = [0 size(app.brightfieldStack,1)];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {397, 397};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {214, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StatAxis = uiaxes(app.LeftPanel);
            title(app.StatAxis, 'Title')
            xlabel(app.StatAxis, 'X')
            ylabel(app.StatAxis, 'Y')
            zlabel(app.StatAxis, 'Z')
            app.OpenFolderButton = uibutton(app.LeftPanel, 'push');
            app.OpenFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolderButton.Text = 'Open Folder';
            app.CropButton = uibutton(app.LeftPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.LoadingLampLabel = uilabel(app.LeftPanel);
            app.LoadingLampLabel.Text = 'Loading';
            app.LoadingLamp = uilamp(app.LeftPanel);
            app.ReadyLampLabel = uilabel(app.LeftPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.LeftPanel);
            app.TrackStatisticsDropDownLabel = uilabel(app.LeftPanel);
            app.TrackStatisticsDropDownLabel.Text = 'Track Statistics';
            app.TrackStatisticsDropDown = uidropdown(app.LeftPanel);
            app.TrackStatisticsDropDown.Items = {'Speed', 'Direction'};
            app.TrackStatisticsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackStatisticsDropDownValueChanged, true);
            app.TrackStatisticsDropDown.Value = 'Speed';
            app.PathEditFieldLabel = uilabel(app.LeftPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.LeftPanel, 'text');
            app.TrackSelectorSpinnerLabel = uilabel(app.LeftPanel);
            app.TrackSelectorSpinnerLabel.Text = 'Track Selector';
            app.TrackSelectorSpinner = uispinner(app.LeftPanel);
            app.TrackSelectorSpinner.Limits = [1 Inf];
            app.TrackSelectorSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackSelectorSpinnerValueChanged, true);
            app.TrackSelectorSpinner.Value = 1;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BrightfieldButton = uitogglebutton(app.ButtonGroup);
            app.BrightfieldButton.Text = 'Brightfield';
            app.BrightfieldButton.Value = true;
            app.FluorescenceButton = uitogglebutton(app.ButtonGroup);
            app.FluorescenceButton.Text = 'Fluorescence';
            app.OverlayButton = uitogglebutton(app.ButtonGroup);
            app.OverlayButton.Text = 'Overlay';
            app.Slider = uislider(app.RightPanel);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Tooltip = {'Move slider around to change the stack frame you want to view.'};
            app.CroppedButton = uibutton(app.RightPanel, 'state');
            app.CroppedButton.ValueChangedFcn = createCallbackFcn(app, @CroppedButtonValueChanged, true);
            app.CroppedButton.Text = 'Cropped';
            app.ResetZoomButton = uibutton(app.RightPanel, 'push');
            app.ResetZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomButtonPushed, true);
            app.ResetZoomButton.Text = 'Reset Zoom';
            app.UsemousetozoomscrollorpressCroppedforCELLframeofreferenceLabel = uilabel(app.RightPanel);
            app.UsemousetozoomscrollorpressCroppedforCELLframeofreferenceLabel.Text = 'Use mouse to zoom (scroll), or press "Cropped" for CELL frame of reference. Press "Crop" on the bottom left to save cropped video.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trackSelector

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end