classdef categoriseTracksLiveApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        BatchButtonGroup           matlab.ui.container.ButtonGroup
        BatchButton                matlab.ui.control.ToggleButton
        SingleFolderButton         matlab.ui.control.ToggleButton
        SaveoutputfilesCheckBox    matlab.ui.control.CheckBox
        SavingLamp                 matlab.ui.control.Lamp
        SavingLampLabel            matlab.ui.control.Label
        TypeButtonGroup            matlab.ui.container.ButtonGroup
        UncategorisedButton        matlab.ui.control.ToggleButton
        MovingButton               matlab.ui.control.ToggleButton
        StationaryButton           matlab.ui.control.ToggleButton
        TrackInfoLabel             matlab.ui.control.Label
        ReadyLamp                  matlab.ui.control.Lamp
        ReadyLampLabel             matlab.ui.control.Label
        PreparingLamp              matlab.ui.control.Lamp
        PreparingLampLabel         matlab.ui.control.Label
        LoadingLamp                matlab.ui.control.Lamp
        LoadingLampLabel           matlab.ui.control.Label
        SelectVideoSpinner         matlab.ui.control.Spinner
        SelectVideoSpinnerLabel    matlab.ui.control.Label
        OpenFolderButton           matlab.ui.control.Button
        DropDown                   matlab.ui.control.DropDown
        ShowTracksCheckBox         matlab.ui.control.CheckBox
        ShowLocalisationsCheckBox  matlab.ui.control.CheckBox
        EditField                  matlab.ui.control.EditField
        RightPanel                 matlab.ui.container.Panel
        ButtonGroup                matlab.ui.container.ButtonGroup
        OverlayButton              matlab.ui.control.ToggleButton
        FluorescenceButton         matlab.ui.control.ToggleButton
        BrightfieldButton          matlab.ui.control.ToggleButton
        Slider                     matlab.ui.control.Slider
        LabAxes                    matlab.ui.control.UIAxes
        KymoAxes                   matlab.ui.control.UIAxes
        RotAxes                    matlab.ui.control.UIAxes
        CropAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        brightfieldStack
        fluorescenceStack
        rotatedStack% Description
        fittedLocs
        rotatedLocs
        bacteriaTrack
        %theta
        rotatedBrightfieldStack
        rotatedFluorescenceStack% Description
        rotImage
        hImage% Description
        currentLocs
        currentRLocs
        locsPlot
        rotPlot% Description
        %kymograph
        kymoImage
        kymoLine
        labImage
        labFrameStack
        folderlist
        mainFolder
        croppedCellData
        fluolimits
        kymolimits
        brightlimits
    end
    
    properties (Access = public)
        theta % Description
        kymograph
    end
    
    methods (Access = private)
        
        function reloadImage(app,slice)
            app.CropAxes.CLimMode='manual';
            app.RotAxes.CLimMode='manual';
            app.KymoAxes.CLimMode='manual';
            
            %Limits
            app.KymoAxes.CLim = app.kymolimits;

            slice = ceil(slice);

            if strcmp(app.ButtonGroup.SelectedObject.Text,'Brightfield')
               app.rotImage.CData = app.rotatedBrightfieldStack(:,:,slice);
               app.hImage.CData = app.brightfieldStack(:,:,slice);

               app.RotAxes.CLim = app.brightlimits;
               app.CropAxes.CLim = app.brightlimits;
            elseif strcmp(app.ButtonGroup.SelectedObject.Text,'Fluorescence')
                app.rotImage.CData = app.rotatedFluorescenceStack(:,:,slice);
                app.hImage.CData = app.fluorescenceStack(:,:,slice);

                app.RotAxes.CLim = app.fluolimits;
                app.CropAxes.CLim = app.fluolimits;
            end
            app.labImage.CData = app.labFrameStack(:,:,slice);


           % set(app.kymoLine,'XData', [1 size(app.kymoImage,2)],'YData', [slice slice]);
        end
        
        function loadFolder(app,datafolder)
            %% -- Load up all the files
            app.LoadingLamp.Color = [0 1 0];
            drawnow;
            try
                % Import bacteriaTrack
                app.bacteriaTrack = importdata([datafolder 'bacteriaTrack.mat']);
                app.theta = (app.bacteriaTrack(:,4));
    
                % Import images
                app.brightfieldStack = loadtiff([datafolder 'brightfield_cropped.tif']);
                app.fluorescenceStack = loadtiff([datafolder 'L_cropped.tif']);
                app.LoadingLamp.Color = [1 1 1];
                drawnow;

                % Load crop cell data
                app.croppedCellData = importdata([datafolder 'croppedCellData.mat']);
            catch
                app.LoadingLamp.Color = [1 0 0]; % red
                drawnow;
            end

           
            %% -- PREPARE THE DATA
            app.PreparingLamp.Color = [0 1 0];
            drawnow;
            try
                % LAB FRAME IMAGE
                app.labFrameStack = zeros(1000,1000,size(app.brightfieldStack,3))+225;
                x = app.bacteriaTrack(:,2);
                y = app.bacteriaTrack(:,3);
                %-- APPLY ROTATION, IMPROVE IMAGES
                for i=1:size(app.fluorescenceStack,3)
                    app.brightfieldStack(:,:,i) = imgaussfilt(app.brightfieldStack(:,:,i),2);
                    app.fluorescenceStack(:,:,i) = imgaussfilt(app.fluorescenceStack(:,:,i),2);
                    
                    if isnan(app.theta(i))
                        app.theta(i) = app.theta(i-1);
                    end
    
                    app.rotatedBrightfieldStack(:,:,i) = imrotate(app.brightfieldStack(:,:,i),-app.theta(i),"nearest","crop");
                    app.rotatedFluorescenceStack(:,:,i) = imrotate(app.fluorescenceStack(:,:,i),-app.theta(i),"nearest","crop");
                
                    % ALSO ROTATE LOCALISATIONS
                    %app.currentLocs = app.fittedLocs{i};
                    %if ~isempty(app.currentLocs)
                    %    [x y] = rotatePoints(app.currentLocs(:,1),app.currentLocs(:,2),deg2rad(app.theta(i)),101/2,101/2);
                    %    app.rotatedLocs{i} = [x y];
                    %end
    
                    % RECONSTRUCT LAB FRAME
                    colsIm = (round(x(i))-100)+(1:101);
                    rowsIm = (round(y(i))-100)+(1:101);
                    app.labFrameStack(rowsIm,colsIm,i) = app.brightfieldStack(:,:,i);
                end                
                app.fluolimits = [min(app.fluorescenceStack(:)) max(app.fluorescenceStack(:))];
                app.brightlimits = [min(app.brightfieldStack(:)) max(app.brightfieldStack(:))];

                %-- Make a kymograph from rotated fluorescence stack
                app.kymograph = zeros(size(app.rotatedFluorescenceStack,3),size(app.rotatedFluorescenceStack,2));
                for i=1:size(app.fluorescenceStack,3)
                    app.kymograph(i,:) = max(app.rotatedFluorescenceStack(:,:,i),[],1);
                end
                app.kymolimits = [min(app.kymograph(app.kymograph(:)>0)) max(app.kymograph(:))];


                app.PreparingLamp.Color = [1 1 1];
                drawnow;
            catch
                app.PreparingLamp.Color = [1 0 0];
                drawnow;
            end
            %% -- SET AXES UP
            app.hImage = imshow(app.brightfieldStack(:,:,1),[],'Parent', app.CropAxes);
            hold(app.CropAxes,"on");

            app.rotImage = imshow(app.rotatedBrightfieldStack(:,:,1),[],'Parent',app.RotAxes);
            hold(app.RotAxes,"on");

            app.labImage = imshow(app.labFrameStack(:,:,1),[],'Parent',app.LabAxes);
            try
            set(app.LabAxes, 'XLim', [min(x)-100 max(x)+100], 'YLim',[min(y)-100 max(x)+100]);
            catch
            end

            app.kymoImage = imshow(app.kymograph,[],'Parent',app.KymoAxes);
            %app.KymoAxes.YLim = [1 size(app.kymograph,1)];
            app.KymoAxes.YTickMode = 'auto';
            hold(app.KymoAxes,"off");
%            app.kymoLine = plot(app.KymoAxes,[1 size(app.kymoImage,2)], [1 1],'r-','LineWidth',10);


            % Image limits
            
            app.RotAxes.CLim = app.brightlimits;
            app.CropAxes.CLim = app.brightlimits;
            app.KymoAxes.CLim = app.kymolimits;

            % Fix slider
            app.Slider.Value = 1;
            app.Slider.Limits = [1 size(app.brightfieldStack,3)];

            app.ReadyLamp.Color = [0 1 0];
            drawnow;

            % Category
            app.UncategorisedButton.Enable ="on";
            app.MovingButton.Enable ="on";
            app.StationaryButton.Enable ="on";
            setCategory(app);

            %Change Label
            app.TrackInfoLabel.Text='The cell files have been loaded. Use the buttons below to categorise the motion of the SprB molecule. To save categorisations, the "save output files" box has to be ticked.';

            % Save output
            %app.SaveoutputfilesCheckBox.Enable = "on";
            %app.SaveoutputfilesCheckBox.Value = true;
            if app.SaveoutputfilesCheckBox.Value
                imwrite(uint8(app.kymograph),gray(256),[datafolder 'kymograph.tif']);
            end
        end
        
        function setCategory(app)
            category = app.croppedCellData.category;

            if strcmp(category,'Uncategorised')
                app.UncategorisedButton.Value=true;
            elseif strcmp(category, 'Moving')
                app.MovingButton.Value=true;
            elseif strcmp(category, 'Stationary')
                app.StationaryButton.Value=true;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFolderButton
        function OpenFolderButtonPushed(app, event)
            if ~strcmp(app.BatchButtonGroup.SelectedObject.Text,'Batch')
                % If it's not in batch mode, just open the folder
                datafolder = app.EditField.Value;
            else
                % If it's in batch mode, start by getting a list of all
                % directories
                app.mainFolder = app.EditField.Value;
                app.folderlist = getFoldersAndSubfolders(app.EditField.Value);
                
                app.SelectVideoSpinner.Enable = "on";
                app.SelectVideoSpinner.Value = 1;
                app.SelectVideoSpinner.Limits = [1 length(app.folderlist)];
                
                app.DropDown.Enable = "on";
                app.DropDown.Items = app.folderlist';
                app.DropDown.Value = app.folderlist{1};
                app.DropDown.ItemsData = 1:length(app.folderlist);

                datafolder = [app.mainFolder app.folderlist{1}];
            end

            % Load the first, or the only, datafolder
            loadFolder(app,datafolder);          
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            slice = app.Slider.Value;
            reloadImage(app,slice);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = ceil(event.Value);
        %    app.currentLocs = app.fittedLocs{changingValue};
       %     app.currentRLocs = app.rotatedLocs{changingValue};

            reloadImage(app,changingValue);
        end

        % Value changed function: SelectVideoSpinner
        function SelectVideoSpinnerValueChanged(app, event)
            value = app.SelectVideoSpinner.Value;
            datafolder = [app.mainFolder app.folderlist{value}];
            app.DropDown.Value = value;
            loadFolder(app,datafolder);
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.TypeButtonGroup.SelectedObject;
            datafolder = [app.mainFolder app.folderlist{app.SelectVideoSpinner.Value}];
            if app.SaveoutputfilesCheckBox.Value
                app.croppedCellData.category = app.TypeButtonGroup.SelectedObject.Text;
                croppedCellData = app.croppedCellData;
                save([datafolder 'croppedCellData.mat'],'croppedCellData');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {485, 485};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {229, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.ShowLocalisationsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowLocalisationsCheckBox.Enable = 'off';
            app.ShowLocalisationsCheckBox.Text = 'Show Localisations';
            app.ShowTracksCheckBox = uicheckbox(app.LeftPanel);
            app.ShowTracksCheckBox.Enable = 'off';
            app.ShowTracksCheckBox.Text = 'Show Tracks';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = {};
            app.OpenFolderButton = uibutton(app.LeftPanel, 'push');
            app.OpenFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolderButton.Text = 'Open Folder';
            app.SelectVideoSpinnerLabel = uilabel(app.LeftPanel);
            app.SelectVideoSpinnerLabel.Enable = 'off';
            app.SelectVideoSpinnerLabel.Text = 'Select Video';
            app.SelectVideoSpinner = uispinner(app.LeftPanel);
            app.SelectVideoSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectVideoSpinnerValueChanged, true);
            app.SelectVideoSpinner.Enable = 'off';
            app.LoadingLampLabel = uilabel(app.LeftPanel);
            app.LoadingLampLabel.Text = 'Loading';
            app.LoadingLamp = uilamp(app.LeftPanel);
            app.PreparingLampLabel = uilabel(app.LeftPanel);
            app.PreparingLampLabel.Text = 'Preparing';
            app.PreparingLamp = uilamp(app.LeftPanel);
            app.ReadyLampLabel = uilabel(app.LeftPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.LeftPanel);
            app.TrackInfoLabel = uilabel(app.LeftPanel);
            app.TrackInfoLabel.Text = 'No folder has yet been selected for analysis. Please click "Open Folder".';
            app.TypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.StationaryButton = uitogglebutton(app.TypeButtonGroup);
            app.StationaryButton.Enable = 'off';
            app.StationaryButton.Text = 'Stationary';
            app.StationaryButton.Value = true;
            app.MovingButton = uitogglebutton(app.TypeButtonGroup);
            app.MovingButton.Enable = 'off';
            app.MovingButton.Text = 'Moving';
            app.UncategorisedButton = uitogglebutton(app.TypeButtonGroup);
            app.UncategorisedButton.Enable = 'off';
            app.UncategorisedButton.Text = 'Uncategorised';
            app.SavingLampLabel = uilabel(app.LeftPanel);
            app.SavingLampLabel.Text = 'Saving';
            app.SavingLamp = uilamp(app.LeftPanel);
            app.SaveoutputfilesCheckBox = uicheckbox(app.LeftPanel);
            app.SaveoutputfilesCheckBox.Text = 'Save output files';
            app.BatchButtonGroup = uibuttongroup(app.LeftPanel);
            app.SingleFolderButton = uitogglebutton(app.BatchButtonGroup);
            app.SingleFolderButton.Text = 'Single Folder';
            app.SingleFolderButton.Value = true;
            app.BatchButton = uitogglebutton(app.BatchButtonGroup);
            app.BatchButton.Text = 'Batch';
            app.RightPanel = uipanel(app.GridLayout);
            app.CropAxes = uiaxes(app.RightPanel);
            title(app.CropAxes, 'Cropped Image')
            xlabel(app.CropAxes, 'X')
            ylabel(app.CropAxes, 'Y')
            zlabel(app.CropAxes, 'Z')
            app.CropAxes.XTick = [];
            app.CropAxes.YTick = [];
            app.RotAxes = uiaxes(app.RightPanel);
            title(app.RotAxes, 'Rotated Image')
            xlabel(app.RotAxes, 'X')
            ylabel(app.RotAxes, 'Y')
            zlabel(app.RotAxes, 'Z')
            app.RotAxes.XTick = [];
            app.RotAxes.YTick = [];
            app.KymoAxes = uiaxes(app.RightPanel);
            title(app.KymoAxes, 'Kymograph')
            xlabel(app.KymoAxes, 'Position')
            ylabel(app.KymoAxes, 'Time')
            zlabel(app.KymoAxes, 'Z')
            app.KymoAxes.XTick = [];
            app.KymoAxes.YTick = [];
            app.LabAxes = uiaxes(app.RightPanel);
            title(app.LabAxes, 'Lab Frame')
            xlabel(app.LabAxes, 'X')
            ylabel(app.LabAxes, 'Y')
            zlabel(app.LabAxes, 'Z')
            app.LabAxes.XTick = [];
            app.LabAxes.YTick = [];
            app.Slider = uislider(app.RightPanel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BrightfieldButton = uitogglebutton(app.ButtonGroup);
            app.BrightfieldButton.Text = 'Brightfield';
            app.BrightfieldButton.Value = true;
            app.FluorescenceButton = uitogglebutton(app.ButtonGroup);
            app.FluorescenceButton.Text = 'Fluorescence';
            app.OverlayButton = uitogglebutton(app.ButtonGroup);
            app.OverlayButton.Enable = 'off';
            app.OverlayButton.Text = 'Overlay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = categoriseTracksLiveApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end