classdef spotFinderLiveCheckCropped < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LRChoice                        matlab.ui.container.ButtonGroup
        RButton                         matlab.ui.control.RadioButton
        LButton                         matlab.ui.control.RadioButton
        Label                           matlab.ui.control.Label
        FolderSpinner                   matlab.ui.control.Spinner
        FolderSpinnerLabel              matlab.ui.control.Label
        BatchButtonGroup                matlab.ui.container.ButtonGroup
        BatchButton                     matlab.ui.control.ToggleButton
        SingleFolderButton              matlab.ui.control.ToggleButton
        TrackButton                     matlab.ui.control.Button
        ApplyWatershedTransformCheckBox  matlab.ui.control.CheckBox
        ImageOpeningRadiusEditField     matlab.ui.control.NumericEditField
        ImageOpeningRadiusEditFieldLabel  matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        MaxnumberofSpotsEditField       matlab.ui.control.NumericEditField
        MaxnumberofSpotsEditFieldLabel  matlab.ui.control.Label
        MaxAreaEditField                matlab.ui.control.NumericEditField
        MaxAreaEditFieldLabel           matlab.ui.control.Label
        MinAreaEditField                matlab.ui.control.NumericEditField
        MinAreaEditFieldLabel           matlab.ui.control.Label
        MinIntensityEditField           matlab.ui.control.NumericEditField
        MinIntensityEditFieldLabel      matlab.ui.control.Label
        TopHatRadiusEditField           matlab.ui.control.NumericEditField
        TopHatRadiusEditFieldLabel      matlab.ui.control.Label
        GaussianBlurRadiusEditField     matlab.ui.control.NumericEditField
        GaussianBlurRadiusEditFieldLabel  matlab.ui.control.Label
        ExportSettingsButton            matlab.ui.control.Button
        CheckBoxOpening                 matlab.ui.control.CheckBox
        LoadingLamp                     matlab.ui.control.Lamp
        LoadingLampLabel                matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                  matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        StackPathEditField              matlab.ui.control.EditField
        FilteringLabel                  matlab.ui.control.Label
        ThresholdingLabel               matlab.ui.control.Label
        ImagePreparationLabel           matlab.ui.control.Label
        CheckBoxTopHat                  matlab.ui.control.CheckBox
        CheckBoxGaussBlur               matlab.ui.control.CheckBox
        FlatFieldBasedonDoGCheckBox     matlab.ui.control.CheckBox
        NonLocalMeansDenoiseCheckBox    matlab.ui.control.CheckBox
        StackPathEditFieldLabel         matlab.ui.control.Label
        SpotsFoundOverlayCheckBox       matlab.ui.control.CheckBox
        BinaryImageOverlayCheckBox      matlab.ui.control.CheckBox
        ProcessedImageCheckBox          matlab.ui.control.CheckBox
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainFolder
        %folderlist
        currentStackPath
        folderpathlist % Description
    end
    
    properties (Access = public)
        stack % Description
        frame % Description
        processedImage
        binaryImage
        centroids
        hImage % Description
        options % Description
        spotPlot
        folderlist
    end
    
    methods (Access = private)
        
        function readOptionValues(app)
            app.options.flatfield = app.FlatFieldBasedonDoGCheckBox.Value; % apply flat-field?
            app.options.nonlocaldenoise = app.NonLocalMeansDenoiseCheckBox.Value; % apply non-local means denoising?

            app.options.gaussian = app.CheckBoxGaussBlur.Value; % apply gaussian blur?
            app.options.tophat = app.CheckBoxTopHat.Value; % apply tophat transform?
            app.options.gaussianradius = app.GaussianBlurRadiusEditField.Value;
            app.options.tophatradius = app.TopHatRadiusEditField.Value; 
            app.options.threshold = app.ThresholdEditField.Value;
            app.options.output = false;
            
            % Binary Image processing
            app.options.openImage = app.CheckBoxOpening.Value;
            app.options.diskRadius = app.ImageOpeningRadiusEditField.Value;
            app.options.applyWatershed = app.ApplyWatershedTransformCheckBox.Value;

            % Filtering
            app.options.minIntensity = app.MinIntensityEditField.Value;
            app.options.maxArea = app.MaxAreaEditField.Value;
            app.options.minArea = app.MinAreaEditField.Value;
            app.options.nSpots = app.MaxnumberofSpotsEditField.Value;
        end
        
        function reloadImage(app)
            % DECIDE WHAT IMAGE TO PLOT
            if app.ProcessedImageCheckBox.Value == true && app.BinaryImageOverlayCheckBox.Value == false
                % In this case only show processed image
                app.hImage.CData = app.processedImage;
            elseif app.ProcessedImageCheckBox.Value == true && app.BinaryImageOverlayCheckBox.Value == true
                % Show both processed and binary overlay
                C = imfuse(app.processedImage,app.binaryImage);
                app.hImage.CData = C;
            elseif app.ProcessedImageCheckBox.Value == false && app.BinaryImageOverlayCheckBox.Value == false
                app.hImage.CData = app.frame;
            elseif app.ProcessedImageCheckBox.Value == false && app.BinaryImageOverlayCheckBox.Value == true
                C = imfuse(app.frame,app.binaryImage);
                app.hImage.CData = C;
            end
            
            % Scale image colorbar
            app.UIAxes.CLimMode='auto';

            % PLOT SPOTS FOUND
            if app.SpotsFoundOverlayCheckBox.Value
                if ~isempty(app.centroids)
                    set(app.spotPlot,'XData',app.centroids(:,1),'YData',app.centroids(:,2));
                end                
            else
                set(app.spotPlot,'XData',nan,'YData',nan);
            end
        end
        
        function results = openStack(app)
            app.LoadingLamp.Color = [0 1 0];
            try
            app.stack = loadtiff(app.currentStackPath); % Load the stack
            app.frame = app.stack(:,:,1);
            
            app.LoadingLamp.Color = [1 1 1];
            app.ReadyLamp.Color = [0 1 0];


            %-- Now show image
            hold(app.UIAxes,'off');
            app.hImage = imshow(app.frame,[],'Parent', app.UIAxes);
            hold(app.UIAxes,"on");
            app.spotPlot = plot(app.UIAxes,nan,nan,'o');

            %-- And set up various parameters for the axes
            app.UIAxes.XLim = [0 size(app.frame,2)];
            app.UIAxes.YLim = [0 size(app.frame,1)];
            app.UIAxes.Visible = false;

            %-- Set slider min and max
            app.Slider.Value = 1;
            app.Slider.Limits = [1 size(app.stack,3)];
            catch
            app.LoadingLamp.Color = [1 0 0];
            end

            %-- Run function for the first time
            [app.processedImage, app.binaryImage, app.centroids] = findSpotsSCv3(app.frame,app.options);
            reloadImage(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            readOptionValues(app);
            %[app.processedImage, app.binaryImage, app.centroids] = findSpotsSCv3(app.frame,app.options);
            %reloadImage(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if strcmp(app.BatchButtonGroup.SelectedObject.Text,'Batch')
                % If it's not in batch mode, just open the folder
                app.mainFolder = app.StackPathEditField.Value;
                app.folderlist = getFoldersAndSubfolders(app.mainFolder);
                
                % Remove translated and rotated
                for i=1:length(app.folderlist)
                    if contains(app.folderlist{i},'translatedAndRotated')
                        app.folderlist{i} = [];
                    end
                end


                app.FolderSpinner.Enable = "on";
                app.FolderSpinner.Value = 1;
                app.FolderSpinner.Limits = [1 length(app.folderlist)];
                
                app.Label.Text = [num2str(length(app.folderlist)) ' folders found.'];
                if strcmp(app.LRChoice.SelectedObject.Text,'L')
                    app.currentStackPath = [app.mainFolder app.folderlist{1} 'L_cropped.tif'];
                elseif strcmp(app.LRChoice.SelectedObject.Text,'R')
                    app.currentStackPath = [app.mainFolder app.folderlist{1} 'R_cropped.tif'];
                end
            else
                app.currentStackPath = app.StackPathEditField.Value;
            end
            openStack(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            %-- If the value has changed, change what the current frame is,
            %and update plot
            
            app.frame = app.stack(:,:,changingValue);
            %app.hImage.CData = app.frame;
            
            readOptionValues(app);
            [app.processedImage, app.binaryImage, app.centroids] = findSpotsSCv3(app.frame,app.options);

            reloadImage(app);
        end

        % Value changed function: CheckBoxOpening, CheckBoxTopHat, 
        % ...and 10 other components
        function ProcessingValueChanged(app, event)
            readOptionValues(app);
            [app.processedImage, app.binaryImage, app.centroids] = findSpotsSCv3(app.frame,app.options);

            reloadImage(app);
        end

        % Value changed function: BinaryImageOverlayCheckBox, 
        % ...and 2 other components
        function ImageSelectionCheckBoxValueChanged(app, event)
            reloadImage(app);
            % if app.ProcessedImageCheckBox.Value
            %     app.hImage.CData=app.processedImage;
            % else
            %     app.hImage.CData=app.frame;
            % end
        end

        % Button pushed function: ExportSettingsButton
        function ExportSettingsButtonPushed(app, event)
            spotFinderOptions = app.options;
            uisave('spotFinderOptions', 'spotFinderOptions.mat');
        end

        % Button pushed function: TrackButton
        function TrackButtonPushed(app, event)
            getLocalisationsHorizontalStack(app.currentStackPath,app.options);
            
            spotFinderOptions = app.options;
            save(strrep(app.currentStackPath,'.tif','options.mat'), 'spotFinderOptions');
        end

        % Value changed function: FolderSpinner
        function FolderSpinnerValueChanged(app, event)
            value = app.FolderSpinner.Value;

            if strcmp(app.LRChoice.SelectedObject.Text,'L')
                app.currentStackPath = [app.mainFolder app.folderlist{value} 'L_cropped.tif'];
            elseif strcmp(app.LRChoice.SelectedObject.Text,'R')
                app.currentStackPath = [app.mainFolder app.folderlist{value} 'R_cropped.tif'];
            end
            openStack(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'gray')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Tooltip = {'Move slider around to change the stack frame you want to view.'};
            app.ProcessedImageCheckBox = uicheckbox(app.UIFigure);
            app.ProcessedImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionCheckBoxValueChanged, true);
            app.ProcessedImageCheckBox.Text = 'Processed Image?';
            app.ProcessedImageCheckBox.Value = true;
            app.BinaryImageOverlayCheckBox = uicheckbox(app.UIFigure);
            app.BinaryImageOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionCheckBoxValueChanged, true);
            app.BinaryImageOverlayCheckBox.Text = 'Binary Image (Overlay)';
            app.BinaryImageOverlayCheckBox.Value = true;
            app.SpotsFoundOverlayCheckBox = uicheckbox(app.UIFigure);
            app.SpotsFoundOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionCheckBoxValueChanged, true);
            app.SpotsFoundOverlayCheckBox.Text = 'Spots Found (Overlay)';
            app.SpotsFoundOverlayCheckBox.Value = true;
            app.StackPathEditFieldLabel = uilabel(app.UIFigure);
            app.StackPathEditFieldLabel.Text = 'Stack Path';
            app.NonLocalMeansDenoiseCheckBox = uicheckbox(app.UIFigure);
            app.NonLocalMeansDenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.NonLocalMeansDenoiseCheckBox.Text = 'Non Local Means Denoise';
            app.FlatFieldBasedonDoGCheckBox = uicheckbox(app.UIFigure);
            app.FlatFieldBasedonDoGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.FlatFieldBasedonDoGCheckBox.Text = 'Flat Field (Based on DoG)';
            app.CheckBoxGaussBlur = uicheckbox(app.UIFigure);
            app.CheckBoxGaussBlur.Text = '';
            app.CheckBoxGaussBlur.Value = true;
            app.CheckBoxTopHat = uicheckbox(app.UIFigure);
            app.CheckBoxTopHat.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.CheckBoxTopHat.Text = '';
            app.ImagePreparationLabel = uilabel(app.UIFigure);
            app.ImagePreparationLabel.Text = 'Image Preparation';
            app.ThresholdingLabel = uilabel(app.UIFigure);
            app.ThresholdingLabel.Text = 'Thresholding';
            app.FilteringLabel = uilabel(app.UIFigure);
            app.FilteringLabel.Text = 'Filtering';
            app.StackPathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.LoadingLampLabel = uilabel(app.UIFigure);
            app.LoadingLampLabel.Text = 'Loading';
            app.LoadingLamp = uilamp(app.UIFigure);
            app.CheckBoxOpening = uicheckbox(app.UIFigure);
            app.CheckBoxOpening.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.CheckBoxOpening.Text = '';
            app.ExportSettingsButton = uibutton(app.UIFigure, 'push');
            app.ExportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton.Text = 'Export Settings';
            app.GaussianBlurRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.GaussianBlurRadiusEditFieldLabel.Text = 'Gaussian Blur Radius';
            app.GaussianBlurRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaussianBlurRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.GaussianBlurRadiusEditField.Value = 3;
            app.TopHatRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.TopHatRadiusEditFieldLabel.Text = 'Top Hat Radius';
            app.TopHatRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.TopHatRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.TopHatRadiusEditField.Value = 20;
            app.MinIntensityEditFieldLabel = uilabel(app.UIFigure);
            app.MinIntensityEditFieldLabel.Text = 'Min Intensity';
            app.MinIntensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinIntensityEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.MinAreaEditFieldLabel = uilabel(app.UIFigure);
            app.MinAreaEditFieldLabel.Text = 'Min Area';
            app.MinAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinAreaEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.MaxAreaEditFieldLabel = uilabel(app.UIFigure);
            app.MaxAreaEditFieldLabel.Text = 'Max Area';
            app.MaxAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxAreaEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.MaxAreaEditField.Value = 1000;
            app.MaxnumberofSpotsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxnumberofSpotsEditFieldLabel.Text = 'Max number of Spots';
            app.MaxnumberofSpotsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxnumberofSpotsEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.MaxnumberofSpotsEditField.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.ThresholdEditField.Value = 77;
            app.ImageOpeningRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.ImageOpeningRadiusEditFieldLabel.Text = 'Image Opening Radius';
            app.ImageOpeningRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageOpeningRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessingValueChanged, true);
            app.ImageOpeningRadiusEditField.Value = 2;
            app.ApplyWatershedTransformCheckBox = uicheckbox(app.UIFigure);
            app.ApplyWatershedTransformCheckBox.Text = 'Apply Watershed Transform';
            app.TrackButton = uibutton(app.UIFigure, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @TrackButtonPushed, true);
            app.TrackButton.Text = 'Track';
            app.BatchButtonGroup = uibuttongroup(app.UIFigure);
            app.SingleFolderButton = uitogglebutton(app.BatchButtonGroup);
            app.SingleFolderButton.Text = 'Single Folder';
            app.SingleFolderButton.Value = true;
            app.BatchButton = uitogglebutton(app.BatchButtonGroup);
            app.BatchButton.Text = 'Batch';
            app.FolderSpinnerLabel = uilabel(app.UIFigure);
            app.FolderSpinnerLabel.Enable = 'off';
            app.FolderSpinnerLabel.Text = 'Folder';
            app.FolderSpinner = uispinner(app.UIFigure);
            app.FolderSpinner.ValueChangedFcn = createCallbackFcn(app, @FolderSpinnerValueChanged, true);
            app.FolderSpinner.Enable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LRChoice = uibuttongroup(app.UIFigure);
            app.LButton = uiradiobutton(app.LRChoice);
            app.LButton.Text = 'L';
            app.LButton.Value = true;
            app.RButton = uiradiobutton(app.LRChoice);
            app.RButton.Text = 'R';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spotFinderLiveCheckCropped

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end