classdef trackAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        SelectTrackSpinner         matlab.ui.control.Spinner
        SelectTrackSpinnerLabel    matlab.ui.control.Label
        ShowTracksCheckBox         matlab.ui.control.CheckBox
        ShowLocalisationsCheckBox  matlab.ui.control.CheckBox
        EditField                  matlab.ui.control.EditField
        OpenFolderButton           matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        ButtonGroup                matlab.ui.container.ButtonGroup
        OverlayButton              matlab.ui.control.ToggleButton
        FluorescenceButton         matlab.ui.control.ToggleButton
        BrightfieldButton          matlab.ui.control.ToggleButton
        Slider                     matlab.ui.control.Slider
        RotAxes                    matlab.ui.control.UIAxes
        PlotAxes                   matlab.ui.control.UIAxes
        ImAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        brightfieldStack
        fluorescenceStack
        rotatedStack% Description
        fittedLocs
        rotatedLocs
        bacteriaTrack
        %theta
        rotatedBrightfieldStack
        rotatedFluorescenceStack% Description
        rotImage
        hImage% Description
        currentLocs
        currentRLocs
        locsPlot
        rotPlot% Description
    end
    
    properties (Access = public)
        theta % Description
    end
    
    methods (Access = private)
        
        function reloadImage(app,slice)
            slice = ceil(slice);

            if strcmp(app.ButtonGroup.SelectedObject.Text,'Brightfield')
               app.rotImage.CData = app.rotatedBrightfieldStack(:,:,slice);
               app.hImage.CData = app.brightfieldStack(:,:,slice);
            elseif strcmp(app.ButtonGroup.SelectedObject.Text,'Fluorescence')
                app.rotImage.CData = app.rotatedFluorescenceStack(:,:,slice);
                app.hImage.CData = app.fluorescenceStack(:,:,slice);
            end
            app.ImAxes.CLimMode='auto';
            app.RotAxes.CLimMode='auto';

            if ~isempty(app.currentLocs)
                set(app.locsPlot,'XData',app.currentLocs(:,1),'YData',app.currentLocs(:,2));
                set(app.rotPlot,'XData',app.currentRLocs(:,1),'YData',app.currentRLocs(:,2));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFolderButton
        function OpenFolderButtonPushed(app, event)
            datafolder = app.EditField.Value;
            %-- Load up all the files
            % Import bacteriaTrack
            app.bacteriaTrack = importdata([datafolder 'bacteriaTrack.mat']);
            app.theta = (app.bacteriaTrack(:,4));

            % Import images
            app.brightfieldStack = loadtiff([datafolder 'brightfield_cropped.tif']);
            app.fluorescenceStack = loadtiff([datafolder 'L_cropped.tif']);

            %-- Import localisations
            app.fittedLocs = importdata([datafolder 'L_localisations.mat']);


            %-- APPLY ROTATION, IMPROVE IMAGES
            for i=1:size(app.fluorescenceStack,3)
                app.brightfieldStack(:,:,i) = imgaussfilt(app.brightfieldStack(:,:,i),2);
                app.fluorescenceStack(:,:,i) = imgaussfilt(app.fluorescenceStack(:,:,i),2);
                
                if isnan(app.theta(i))
                    app.theta(i) = app.theta(i-1);
                end

                app.rotatedBrightfieldStack(:,:,i) = imrotate(app.brightfieldStack(:,:,i),-app.theta(i),"nearest","crop");
                app.rotatedFluorescenceStack(:,:,i) = imrotate(app.fluorescenceStack(:,:,i),-app.theta(i),"nearest","crop");
            
                % ALSO ROTATE LOCALISATIONS
                app.currentLocs = app.fittedLocs{i};
                if ~isempty(app.currentLocs)
                    [x y] = rotatePoints(app.currentLocs(:,1),app.currentLocs(:,2),deg2rad(app.theta(i)),101/2,101/2);
                    app.rotatedLocs{i} = [x y];
                end
            end


            % SET AXES UP
            app.hImage = imshow(app.brightfieldStack(:,:,1),[],'Parent', app.ImAxes);
            hold(app.ImAxes,"on");

            app.rotImage = imshow(app.rotatedBrightfieldStack(:,:,1),[],'Parent',app.RotAxes);
            hold(app.RotAxes,"on");

            % Set-up localisations
            app.currentLocs = app.fittedLocs{1};
            app.currentRLocs = app.rotatedLocs{1};

            app.locsPlot = plot(app.ImAxes,app.currentLocs(:,1),app.currentLocs(:,2),'r.');
            app.rotPlot = plot(app.RotAxes,app.currentRLocs(:,1),app.currentRLocs(:,2),'r.');
            % Fix slider
            app.Slider.Value = 1;
            app.Slider.Limits = [1 size(app.brightfieldStack,3)];
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            slice = app.Slider.Value;
            reloadImage(app,slice);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            app.currentLocs = app.fittedLocs{changingValue};
            app.currentRLocs = app.rotatedLocs{changingValue};

            reloadImage(app,changingValue);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.OpenFolderButton = uibutton(app.GridLayout2, 'push');
            app.OpenFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolderButton.Text = 'Open Folder';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.ShowLocalisationsCheckBox = uicheckbox(app.GridLayout2);
            app.ShowLocalisationsCheckBox.Text = 'Show Localisations';
            app.ShowTracksCheckBox = uicheckbox(app.GridLayout2);
            app.ShowTracksCheckBox.Text = 'Show Tracks';
            app.SelectTrackSpinnerLabel = uilabel(app.GridLayout2);
            app.SelectTrackSpinnerLabel.Text = 'Select Track';
            app.SelectTrackSpinner = uispinner(app.GridLayout2);
            app.RightPanel = uipanel(app.GridLayout);
            app.ImAxes = uiaxes(app.RightPanel);
            title(app.ImAxes, 'Title')
            xlabel(app.ImAxes, 'X')
            ylabel(app.ImAxes, 'Y')
            zlabel(app.ImAxes, 'Z')
            app.ImAxes.XTick = [];
            app.ImAxes.YTick = [];
            app.PlotAxes = uiaxes(app.RightPanel);
            title(app.PlotAxes, 'Title')
            xlabel(app.PlotAxes, 'X')
            ylabel(app.PlotAxes, 'Y')
            zlabel(app.PlotAxes, 'Z')
            app.PlotAxes.XTick = [];
            app.PlotAxes.YTick = [];
            app.RotAxes = uiaxes(app.RightPanel);
            title(app.RotAxes, 'Title')
            xlabel(app.RotAxes, 'X')
            ylabel(app.RotAxes, 'Y')
            zlabel(app.RotAxes, 'Z')
            app.RotAxes.XTick = [];
            app.RotAxes.YTick = [];
            app.Slider = uislider(app.RightPanel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BrightfieldButton = uitogglebutton(app.ButtonGroup);
            app.BrightfieldButton.Text = 'Brightfield';
            app.BrightfieldButton.Value = true;
            app.FluorescenceButton = uitogglebutton(app.ButtonGroup);
            app.FluorescenceButton.Text = 'Fluorescence';
            app.OverlayButton = uitogglebutton(app.ButtonGroup);
            app.OverlayButton.Text = 'Overlay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trackAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end