classdef image_params_tester < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        RunspotFinderButton         matlab.ui.control.Button
        NOTFUNCTIONALLabel          matlab.ui.control.Label
        ExportParametersButton      matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel     matlab.ui.control.Label
        BinarizeCheckBox            matlab.ui.control.CheckBox
        TopHatRadiusEditField       matlab.ui.control.NumericEditField
        TopHatRadiusEditFieldLabel  matlab.ui.control.Label
        BlurRadiusEditField         matlab.ui.control.NumericEditField
        BlurRadiusEditFieldLabel    matlab.ui.control.Label
        FrameEditField              matlab.ui.control.NumericEditField
        FrameEditFieldLabel         matlab.ui.control.Label
        GaussianBlurCheckBox        matlab.ui.control.CheckBox
        TopHatCheckBox              matlab.ui.control.CheckBox
        LoadButton                  matlab.ui.control.StateButton
        StackPathEditField          matlab.ui.control.EditField
        StackPathEditFieldLabel     matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Frame % Description
        FrameTransformed % Frame after blur and top hat have been applied to it
    end
    
    methods (Access = private)
        
        function transformedFrame = applyTransforms(app, Frame)
            transformedFrame = Frame;

            GaussBlurFlag = app.GaussianBlurCheckBox.Value;
            TopHatFlag = app.TopHatCheckBox.Value;

            GaussBlurRadius = app.BlurRadiusEditField.Value;
            TopHatRadius = app.TopHatRadiusEditField.Value;

            if GaussBlurFlag
                transformedFrame = imgaussfilt(transformedFrame, GaussBlurRadius);
            end

            if TopHatFlag
                transformedFrame = imtophat(transformedFrame, strel('disk', TopHatRadius));
            end            
        end
        
        function updateEverything(app)
            app.FrameTransformed = applyTransforms(app, app.Frame);

            if app.BinarizeCheckBox.Value
                imageNormalised = app.FrameTransformed./max(app.FrameTransformed,[],'all'); % normalise image 
                imageBinary = imageNormalised>app.ThresholdEditField.Value; % apply a threshold to get spots
                
                imshowpair(app.FrameTransformed,imageBinary, 'Parent', app.UIAxes);
            else
                imshow(app.FrameTransformed, [], 'Parent', app.UIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadButton
        function LoadButtonValueChanged(app, event)
            framenumber = app.FrameEditField.Value;
            framepath = app.StackPathEditField.Value;
            app.Frame = mat2gray(imread(framepath,framenumber));

            updateEverything(app);
        end

        % Value changed function: BinarizeCheckBox, BlurRadiusEditField, 
        % ...and 4 other components
        function ValueChangedCallback(app, event)
            updateEverything(app);
        end

        % Button pushed function: RunspotFinderButton
        function RunspotFinderButtonPushed(app, event)
            options.gaussian = app.GaussianBlurCheckBox.Value; % apply gaussian blur?
            options.tophat = app.TopHatCheckBox.Value; % apply tophat transform?
            options.gaussianradius = app.BlurRadiusEditField.Value; % px
            options.tophatradius = app.TopHatRadiusEditField.Value; % px
            options.threshold = app.ThresholdEditField.Value;
            options.output = false;

            [~, centroids] = findSpotsSCv2(app.Frame,options);

            hold(app.UIAxes,'on');
            plot(app.UIAxes,centroids(:,1),centroids(:,2),'bo','LineWidth',2);
            hold(app.UIAxes,'off');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {433, 433};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {262, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StackPathEditFieldLabel = uilabel(app.LeftPanel);
            app.StackPathEditFieldLabel.Text = 'Stack Path';
            app.StackPathEditField = uieditfield(app.LeftPanel, 'text');
            app.LoadButton = uibutton(app.LeftPanel, 'state');
            app.LoadButton.ValueChangedFcn = createCallbackFcn(app, @LoadButtonValueChanged, true);
            app.LoadButton.Text = 'Load';
            app.TopHatCheckBox = uicheckbox(app.LeftPanel);
            app.TopHatCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChangedCallback, true);
            app.TopHatCheckBox.Text = 'Top Hat';
            app.GaussianBlurCheckBox = uicheckbox(app.LeftPanel);
            app.GaussianBlurCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChangedCallback, true);
            app.GaussianBlurCheckBox.Text = 'Gaussian Blur';
            app.FrameEditFieldLabel = uilabel(app.LeftPanel);
            app.FrameEditFieldLabel.Text = 'Frame #';
            app.FrameEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrameEditField.Limits = [1 Inf];
            app.FrameEditField.Value = 1;
            app.BlurRadiusEditFieldLabel = uilabel(app.LeftPanel);
            app.BlurRadiusEditFieldLabel.Text = 'Blur Radius';
            app.BlurRadiusEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BlurRadiusEditField.Limits = [1 Inf];
            app.BlurRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ValueChangedCallback, true);
            app.BlurRadiusEditField.Value = 1;
            app.TopHatRadiusEditFieldLabel = uilabel(app.LeftPanel);
            app.TopHatRadiusEditFieldLabel.Text = 'Top-Hat Radius';
            app.TopHatRadiusEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TopHatRadiusEditField.Limits = [1 Inf];
            app.TopHatRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ValueChangedCallback, true);
            app.TopHatRadiusEditField.Value = 1;
            app.BinarizeCheckBox = uicheckbox(app.LeftPanel);
            app.BinarizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChangedCallback, true);
            app.BinarizeCheckBox.Text = 'Binarize?';
            app.ThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ValueChangedCallback, true);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '[0-1]';
            app.ExportParametersButton = uibutton(app.LeftPanel, 'push');
            app.ExportParametersButton.Text = 'Export Parameters';
            app.NOTFUNCTIONALLabel = uilabel(app.LeftPanel);
            app.NOTFUNCTIONALLabel.Text = 'NOT FUNCTIONAL';
            app.RunspotFinderButton = uibutton(app.LeftPanel, 'push');
            app.RunspotFinderButton.ButtonPushedFcn = createCallbackFcn(app, @RunspotFinderButtonPushed, true);
            app.RunspotFinderButton.Text = 'Run spotFinder';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_params_tester

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end